classdef AMC4030 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        XSpeedEditFieldLabel     matlab.ui.control.Label
        XSpeedEditField          matlab.ui.control.NumericEditField
        YSpeedEditFieldLabel     matlab.ui.control.Label
        YSpeedEditField          matlab.ui.control.NumericEditField
        MoveXButton              matlab.ui.control.Button
        MoveYButton              matlab.ui.control.Button
        XDistanceEditFieldLabel  matlab.ui.control.Label
        XDistanceEditField       matlab.ui.control.NumericEditField
        YDistanceEditFieldLabel  matlab.ui.control.Label
        YDistanceEditField       matlab.ui.control.NumericEditField
        DebugTextAreaLabel       matlab.ui.control.Label
        DebugTextArea            matlab.ui.control.TextArea
        ConnectButton            matlab.ui.control.Button
        ConnectionLampLabel      matlab.ui.control.Label
        ConnectionLamp           matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MoveXButton
        function MoveXButtonPushed(app, event)
            if 1 == COM_API_Jog(0,app.XDistanceEditField.Value,app.XSpeedEditField.Value)
                app.DebugTextArea.Value = "Successfully Moved X-Axis " + app.XDistanceEditField.Value + "mm, at " + app.XSpeedEditField.Value + "mm/s";
            else
                app.DebugTextArea.Value = "X-Axis Jog Failed";
            end
        end

        % Button pushed function: MoveYButton
        function MoveYButtonPushed(app, event)
            if 1 == COM_API_Jog(1,app.YDistanceEditField.Value,app.YSpeedEditField.Value)
                app.DebugTextArea.Value = "Successfully Moved Y-Axis " + app.YDistanceEditField.Value + "mm, at " + app.YSpeedEditField.Value + "mm/s";
            else
                app.DebugTextArea.Value = "Y-Axis Jog Failed";
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if 1 == COM_API_Connect()
                app.DebugTextArea.Value = "Connection Successful";
                app.ConnectionLamp.Color = "Green";
            else
                app.DebugTextArea.Value = "Connection Failed";
                app.ConnectionLamp.Color = "Red";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.XSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.XSpeedEditFieldLabel.Text = 'X-Speed';
            app.XSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.YSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.YSpeedEditFieldLabel.Text = 'Y-Speed';
            app.YSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveXButton = uibutton(app.UIFigure, 'push');
            app.MoveXButton.ButtonPushedFcn = createCallbackFcn(app, @MoveXButtonPushed, true);
            app.MoveXButton.Text = 'Move X';
            app.MoveYButton = uibutton(app.UIFigure, 'push');
            app.MoveYButton.ButtonPushedFcn = createCallbackFcn(app, @MoveYButtonPushed, true);
            app.MoveYButton.Text = 'Move Y';
            app.XDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.XDistanceEditFieldLabel.Text = 'X-Distance';
            app.XDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.YDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.YDistanceEditFieldLabel.Text = 'Y-Distance';
            app.YDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.DebugTextAreaLabel = uilabel(app.UIFigure);
            app.DebugTextAreaLabel.Text = 'Debug';
            app.DebugTextArea = uitextarea(app.UIFigure);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AMC4030

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end